classdef ModalitiesProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BrowseButton                  matlab.ui.control.Button
        XLabel                        matlab.ui.control.Label
        XSliderAxial                  matlab.ui.control.Slider
        YLabel                        matlab.ui.control.Label
        YSliderAxial                  matlab.ui.control.Slider
        XSliderLabel_2                matlab.ui.control.Label
        XSlidercoronal                matlab.ui.control.Slider
        YSliderLabel_2                matlab.ui.control.Label
        YSlidercoronal                matlab.ui.control.Slider
        XSlider_2Label                matlab.ui.control.Label
        XSlidersagittal               matlab.ui.control.Slider
        YSlider_2Label                matlab.ui.control.Label
        YSlidersagittal               matlab.ui.control.Slider
        lengthinMmEditFieldLabel      matlab.ui.control.Label
        lengthinMmEditField           matlab.ui.control.NumericEditField
        LineButton                    matlab.ui.control.Button
        polygonButton                 matlab.ui.control.Button
        Areainmm2EditFieldLabel       matlab.ui.control.Label
        Areainmm2EditField            matlab.ui.control.NumericEditField
        EllipseButton                 matlab.ui.control.Button
        Areainmm2EditField_2Label     matlab.ui.control.Label
        Areainmm2EditField_2          matlab.ui.control.NumericEditField
        AngleButton                   matlab.ui.control.Button
        AngleindegreesEditFieldLabel  matlab.ui.control.Label
        AngleindegreesEditField       matlab.ui.control.NumericEditField
        ClearObliqueButton            matlab.ui.control.Button
        Axial                         matlab.ui.control.UIAxes
        Sagittal                      matlab.ui.control.UIAxes
        Coronal                       matlab.ui.control.UIAxes
        oblique                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
                MriVolume
                %%%%%%%%%%%%%%%%%%
                AxialXvalue = 256 ;
                AxialYvalue = 256;
                %%%%%%%%%%%%%%%%%%%
                SagittalXvalue = 234;
                SagittalYvalue = 117;
                %%%%%%%%%%%%%%%%%%%
                CoronalXvalue = 234;
                CoronalYvalue = 117;
                %%%%%%%%%%%%%%%%%%%
                ObliquePointX = 73;
                ObliquePointY = 5;
                %%%%%%%%%%%%%%%%%%
                Oangle=0;
                Onormal=0;
                Opoint=0;

    end

   methods (Access = private)
        
        function allevents(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    x = evt.CurrentPosition;
                    x1 = x(1,1);
                    x2 = x(2,1);
                    y1 = x(1,2);
                    y2 = x(2,2);
                    AvgY = (y2-y1);
                    AvgX = (x2-x1);
                    app.ObliquePointX = round((x1+x2)/2);
                    app.ObliquePointY = round((y1+y2)/2);
                    angle=atand(AvgY/AvgX);
                    normal =[AvgY -AvgX 0];
                    point = [app.ObliquePointX app.ObliquePointY 100];
                    Obliqueeshow=(obliqueslice(app.MriVolume,point,normal,'OutputSize','Full'));
                    imshow(imrotate(Obliqueeshow,(angle-180)),[],'Parent',app.oblique);
                    app.Oangle=angle;
                    app.Onormal=normal;
                    app.Opoint=point;
                
        
            end
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           try  
            global V;
                folder = uigetdir;
                disableDefaultInteractivity(app.Axial)
                disableDefaultInteractivity(app.Sagittal)
                disableDefaultInteractivity(app.Coronal)
                disableDefaultInteractivity(app.oblique)
               
                V = dicomreadVolume(folder);                          
                V = squeeze(V); 
                normal = [5 -5 0]; %random normal
                point = [73 3 0]; %random point
                app.MriVolume = V ;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                imshow(V(:,:,55),[],'Parent' ,app.Axial);
                imshow(rot90(permute(V(:,230,:),[1 3 2])),[],'Parent' ,app.Sagittal);
                imshow(rot90(permute(V(200,:,:),[2 3 1])),[],'Parent' ,app.Coronal); 
                roi=drawline(app.Axial,'Position',[100 100;400 400],'Color','b');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.AxialXvalue = xline(app.Axial,app.AxialXvalue,'w');
                app.AxialYvalue = yline(app.Axial,app.AxialYvalue,'w');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.SagittalXvalue=xline(app.Sagittal,app.SagittalXvalue,'w');
                app.SagittalYvalue = yline(app.Sagittal,app.SagittalYvalue,'w');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.CoronalXvalue = xline(app.Coronal,app.CoronalXvalue,'w');
                app.CoronalYvalue = yline(app.Coronal,app.CoronalYvalue,'w');
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                obliquee=obliqueslice(V,point,normal);
                imshow(imrotate(obliquee,-135),[],"Parent",app.oblique)
                addlistener(roi,'ROIMoved',@app.allevents);
                roi.DrawingArea=[10,10,500,500];
           catch 
               warndlg('please select a valid folder')


           end
                
                
        end

        % Value changed function: XSliderAxial
        function XSliderAxialValueChanged(app, event)
            mri=app.MriVolume;
            value = app.XSliderAxial.Value;
            app.AxialXvalue.Value = value ;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(rot90(permute(mri(:,round(value),:),[1 3 2])),[],'Parent' ,app.Sagittal); 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.SagittalXvalue = xline(app.Sagittal,234,'w');
            app.SagittalYvalue = yline(app.Sagittal,117,'w');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Value changed function: YSliderAxial
        function YSliderAxialValueChanged(app, event)
            mri=app.MriVolume;
            value = app.YSliderAxial.Value;
            app.AxialYvalue.Value = value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(rot90(permute(mri(round(value),:,:),[2 3 1])),[],'Parent' ,app.Coronal);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.CoronalXvalue = xline(app.Coronal,234,'w');
            app.CoronalYvalue = yline(app.Coronal,117,'w');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%55


        end

        % Value changed function: XSlidersagittal
        function XSlidersagittalValueChanged(app, event)
            mri=app.MriVolume;
            value = app.XSlidersagittal.Value;
            app.SagittalXvalue.Value=value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(rot90(permute(mri(round(value),:,:),[2 3 1])),[],'Parent' ,app.Coronal); 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.CoronalXvalue = xline(app.Coronal,234,'w');
            app.CoronalYvalue = yline(app.Coronal,117,'w');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Value changed function: YSlidersagittal
        function YSlidersagittalValueChanged(app, event)
           mri=app.MriVolume;
           max=234;
           value = app.YSlidersagittal.Value;
           app.SagittalYvalue.Value=max-value;
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           imshow(mri(:,:,round(value)),[],'Parent' ,app.Axial);
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           app.AxialXvalue = xline(app.Axial,256,'w');
           app.AxialYvalue = yline(app.Axial,256,'w');    
           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           roi=drawline(app.Axial,'Position',[50 50;500 500],'Color','b');
           addlistener(roi,'ROIMoved',@app.allevents);

           roi.DrawingArea=[100,100,400,400];


        end

        % Value changed function: XSlidercoronal
        function XSlidercoronalValueChanged(app, event)
            mri=app.MriVolume; 
            value = app.XSlidercoronal.Value;
            app.CoronalXvalue.Value=(value);
            app.CoronalXvalue.Value=round(app.CoronalXvalue.Value);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(rot90(permute(mri(:,round(value),:),[1 3 2])),[],'Parent' ,app.Sagittal);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.SagittalXvalue = xline(app.Sagittal,234,'w');
            app.SagittalYvalue = yline(app.Sagittal,117,'w');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%55
            
        end

        % Value changed function: YSlidercoronal
        function YSlidercoronalValueChanged(app, event)
            mri=app.MriVolume;
            value = app.YSlidercoronal.Value;
            max=234;
            app.CoronalYvalue.Value=max-value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            imshow(mri(:,:,round(value)),[],'Parent' ,app.Axial);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.AxialXvalue = xline(app.Axial,256,'w');
            app.AxialYvalue = yline(app.Axial,256,'w');    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            roi=drawline(app.Axial,'Position',[50 50;500 500],'Color','b');
            addlistener(roi,'ROIMoved',@app.allevents);

            roi.DrawingArea=[100,100,400,400];


        end

        % Button pushed function: LineButton
        function LineButtonPushed(app, event)
            mri=app.MriVolume;
            normal = app.Onormal; 
            point = app.Opoint;   
            angle = app.Oangle ;
            Obliqueeshow=(obliqueslice(mri,point,normal,'OutputSize','Full'));
            imshow(imrotate(Obliqueeshow,(angle-180)),[],'Parent',app.oblique);
            lineROI=drawline(app.oblique,'LineWidth',0.25);
            lineROI.InteractionsAllowed='none';
            x1=lineROI.Position(1,1);
            y1=lineROI.Position(1,2);
            x2=lineROI.Position(2,1);
            y2=lineROI.Position(2,2);
            length=sqrt((x1-x2)^2+(y1-y2)^2);
            app.lengthinMmEditField.Value=(length);
        end

        % Button pushed function: polygonButton
        function polygonButtonPushed(app, event)
             mri=app.MriVolume;
            normal = app.Onormal; 
            point = app.Opoint;   
            angle = app.Oangle ;
            Obliqueeshow=(obliqueslice(mri,point,normal,'OutputSize','Full'));
            imshow(imrotate(Obliqueeshow,(angle-180)),[],'Parent',app.oblique);      
            polygonROI=drawpolygon(app.oblique,'LineWidth',0.25);
            polygonROI.InteractionsAllowed='none';
            polygonArea=uint32(area(polyshape(polygonROI.Position)));
            app.Areainmm2EditField.Value=double(polygonArea);
        end

        % Button pushed function: EllipseButton
        function EllipseButtonPushed(app, event)
            mri=app.MriVolume;
            normal = app.Onormal; 
            point = app.Opoint;   
            angle = app.Oangle ;
            Obliqueeshow=(obliqueslice(mri,point,normal,'OutputSize','Full'));
            imshow(imrotate(Obliqueeshow,(angle-180)),[],'Parent',app.oblique);   
            ellipseROI=drawellipse(app.oblique,'LineWidth',0.25);
            ellipseROI.InteractionsAllowed='none';
            ellipseArea=pi*ellipseROI.SemiAxes(1)*ellipseROI.SemiAxes(2);
            app.Areainmm2EditField_2.Value=(ellipseArea);
            
        end

        % Button pushed function: AngleButton
        function AngleButtonPushed(app, event)
            mri=app.MriVolume;
            normal = app.Onormal; 
            point = app.Opoint;   
            angle = app.Oangle ;
            Obliqueeshow=(obliqueslice(mri,point,normal,'OutputSize','Full'));
            imshow(imrotate(Obliqueeshow,(angle-180)),[],'Parent',app.oblique);
            line1ROI=drawline(app.oblique,'LineWidth',0.25);
            line1ROI.InteractionsAllowed='none';
            line2ROI=drawline(app.oblique,'LineWidth',0.25);
            line2ROI.InteractionsAllowed='none';   
            x11=line1ROI.Position(1,1);
            y11=line1ROI.Position(1,2);
            x12=line1ROI.Position(2,1);
            y12=line1ROI.Position(2,2); 
            x21=line2ROI.Position(1,1);
            y21=line2ROI.Position(1,2);
            x22=line2ROI.Position(2,1);
            y22=line2ROI.Position(2,2);
            v1 = [x12,y12,0] - [x11,y11,0];
            v2 = [x22,y22,0] - [x21,y21,0];
            Angle=atan2d(norm(cross(v1, v2)), dot(v1, v2));
            app.AngleindegreesEditField.Value=(Angle);
        end

        % Button pushed function: ClearObliqueButton
        function ClearObliqueButtonPushed(app, event)
          try  
            mri=app.MriVolume;
            normal = app.Onormal; 
            point = app.Opoint;   
            angle = app.Oangle ;
            Obliqueeshow=(obliqueslice(mri,point,normal,'OutputSize','Full'));
            imshow(imrotate(Obliqueeshow,(angle-180)),[],'Parent',app.oblique);
            app.lengthinMmEditField.Value=0;
            app.Areainmm2EditField.Value=0;
            app.Areainmm2EditField_2.Value=0;
            app.AngleindegreesEditField.Value=0;
          catch
             

              warndlg('No oblique slice selected');
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = {'X'; ''};
            app.XSliderAxial = uislider(app.UIFigure);
            app.XSliderAxial.Limits = [0 512];
            app.XSliderAxial.ValueChangedFcn = createCallbackFcn(app, @XSliderAxialValueChanged, true);
            app.XSliderAxial.Value = 256;
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.YSliderAxial = uislider(app.UIFigure);
            app.YSliderAxial.Limits = [0 512];
            app.YSliderAxial.ValueChangedFcn = createCallbackFcn(app, @YSliderAxialValueChanged, true);
            app.YSliderAxial.Value = 256;
            app.XSliderLabel_2 = uilabel(app.UIFigure);
            app.XSliderLabel_2.Text = {'X'; ''};
            app.XSlidercoronal = uislider(app.UIFigure);
            app.XSlidercoronal.Limits = [0 468];
            app.XSlidercoronal.ValueChangedFcn = createCallbackFcn(app, @XSlidercoronalValueChanged, true);
            app.XSlidercoronal.Value = 234;
            app.YSliderLabel_2 = uilabel(app.UIFigure);
            app.YSliderLabel_2.Text = {'Y'; ''; ''};
            app.YSlidercoronal = uislider(app.UIFigure);
            app.YSlidercoronal.Limits = [0 234];
            app.YSlidercoronal.ValueChangedFcn = createCallbackFcn(app, @YSlidercoronalValueChanged, true);
            app.YSlidercoronal.Value = 117;
            app.XSlider_2Label = uilabel(app.UIFigure);
            app.XSlider_2Label.Text = {'X'; ''};
            app.XSlidersagittal = uislider(app.UIFigure);
            app.XSlidersagittal.Limits = [0 468];
            app.XSlidersagittal.ValueChangedFcn = createCallbackFcn(app, @XSlidersagittalValueChanged, true);
            app.XSlidersagittal.Value = 234;
            app.YSlider_2Label = uilabel(app.UIFigure);
            app.YSlider_2Label.Text = {'Y'; ''};
            app.YSlidersagittal = uislider(app.UIFigure);
            app.YSlidersagittal.Limits = [0 234];
            app.YSlidersagittal.ValueChangedFcn = createCallbackFcn(app, @YSlidersagittalValueChanged, true);
            app.YSlidersagittal.Value = 117;
            app.lengthinMmEditFieldLabel = uilabel(app.UIFigure);
            app.lengthinMmEditFieldLabel.Text = 'length in Mm';
            app.lengthinMmEditField = uieditfield(app.UIFigure, 'numeric');
            app.lengthinMmEditField.RoundFractionalValues = 'on';
            app.LineButton = uibutton(app.UIFigure, 'push');
            app.LineButton.ButtonPushedFcn = createCallbackFcn(app, @LineButtonPushed, true);
            app.LineButton.Text = {'Line'; ''};
            app.polygonButton = uibutton(app.UIFigure, 'push');
            app.polygonButton.ButtonPushedFcn = createCallbackFcn(app, @polygonButtonPushed, true);
            app.polygonButton.Text = 'polygon';
            app.Areainmm2EditFieldLabel = uilabel(app.UIFigure);
            app.Areainmm2EditFieldLabel.Text = 'Area in mm2';
            app.Areainmm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Areainmm2EditField.RoundFractionalValues = 'on';
            app.EllipseButton = uibutton(app.UIFigure, 'push');
            app.EllipseButton.ButtonPushedFcn = createCallbackFcn(app, @EllipseButtonPushed, true);
            app.EllipseButton.Text = 'Ellipse';
            app.Areainmm2EditField_2Label = uilabel(app.UIFigure);
            app.Areainmm2EditField_2Label.Text = {'Area in mm2'; ''};
            app.Areainmm2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AngleButton = uibutton(app.UIFigure, 'push');
            app.AngleButton.ButtonPushedFcn = createCallbackFcn(app, @AngleButtonPushed, true);
            app.AngleButton.Text = {'Angle'; ''};
            app.AngleindegreesEditFieldLabel = uilabel(app.UIFigure);
            app.AngleindegreesEditFieldLabel.Text = {'Angle in degrees'; ''};
            app.AngleindegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearObliqueButton = uibutton(app.UIFigure, 'push');
            app.ClearObliqueButton.ButtonPushedFcn = createCallbackFcn(app, @ClearObliqueButtonPushed, true);
            app.ClearObliqueButton.Text = {'Clear Oblique'; ''};
            app.Axial = uiaxes(app.UIFigure);
            title(app.Axial, 'Axial')
            app.Sagittal = uiaxes(app.UIFigure);
            title(app.Sagittal, 'Sagittal')
            zlabel(app.Sagittal, 'Z')
            app.Coronal = uiaxes(app.UIFigure);
            title(app.Coronal, 'Coronal')
            app.oblique = uiaxes(app.UIFigure);
            title(app.oblique, 'Oblique')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModalitiesProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end